classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OutputWaveformConfigurationPanel  matlab.ui.container.Panel
        DisplayPanel               matlab.ui.container.Panel
        StartEditField             matlab.ui.control.NumericEditField
        StartEditFieldLabel        matlab.ui.control.Label
        EndEditField               matlab.ui.control.NumericEditField
        EndEditFieldLabel          matlab.ui.control.Label
        VoutLabel                  matlab.ui.control.Label
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        CButton                    matlab.ui.control.RadioButton
        LButton                    matlab.ui.control.RadioButton
        RButton                    matlab.ui.control.RadioButton
        SignalConfigurationPanel   matlab.ui.container.Panel
        AmplitudeSpinner           matlab.ui.control.Spinner
        AmplitudeSpinnerLabel      matlab.ui.control.Label
        FrequencySpinner           matlab.ui.control.Spinner
        FrequencySpinnerLabel      matlab.ui.control.Label
        DropDown_6                 matlab.ui.control.DropDown
        SignalType                 matlab.ui.control.DropDown
        TypeofSignalLabel          matlab.ui.control.Label
        InputSignalAxes            matlab.ui.control.UIAxes
        CircuitConfigurationPanel  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        CEditField                 matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        CSlider                    matlab.ui.control.Slider
        LSlider                    matlab.ui.control.Slider
        LEditField                 matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        RSlider                    matlab.ui.control.Slider
        TypeOfDamp                 matlab.ui.control.TextArea
        TypeofDampLabel            matlab.ui.control.Label
        DropDown_3                 matlab.ui.control.DropDown
        DropDown_2                 matlab.ui.control.DropDown
        DropDown                   matlab.ui.control.DropDown
        Phase_Graph                matlab.ui.control.UIAxes
        Output_wave                matlab.ui.control.UIAxes
        Input_wave                 matlab.ui.control.UIAxes
        Magnitude_Graph            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        r = 0 % resistor
        l = 0 % inductor
        c = 0 % capacitor
        ru = 1 % resistor unit
        lu = 1 % inductor unit
        cu = 1 % capacitor unit
        f = 50 % frequency 
        fu = 1 % frequency unit
        a = 1 % amplitude
        vout = "R" % default Vout
        st = 0 % default start_time
        lt = 0.05 % default end_time
    end
    
    methods (Access = public)
        function results = finddamp(app)
           rx = app.r*app.ru ;
           lx = app.l*app.lu ;
           cx = app.c*app.cu ;

           z=(rx/2)*(sqrt(cx/lx));
           if z>1
               app.TypeOfDamp.Value = "Overdamped" ;
           elseif z==1
               app.TypeOfDamp.Value = "Critically damped" ;
           elseif z<1 
               app.TypeOfDamp.Value = "Underdamped" ;
           end
        end
    end

    methods (Access = public)
        function results = cal_tranfer(app)
           rx = app.r*app.ru ;
           lx = app.l*app.lu ;
           cx = app.c*app.cu ;

           if app.vout == "R"
               G = tf([rx 0],[lx rx 1/cx]);
           elseif app.vout == "L"
               G = tf([lx 0 0],[lx rx 1/cx]);
           elseif app.vout == "C"
               G = tf([1],[lx*cx rx*cx 1]);
           end
           [mag,phase] = bode(G);
           plot(app.Magnitude_Graph,squeeze(mag),'LineWidth',2);
           plot(app.Phase_Graph,squeeze(phase),'LineWidth',2);        
        end
    end
    
    methods (Access = private)
        
        function results = cal_vout(app)
           rx = app.r*app.ru ;
           lx = app.l*app.lu ;
           cx = app.c*app.cu ;

           t=(app.st:1/(app.f*app.fu*100):app.lt) ;
           unitstep = t>=0;

           if app.vout == "R"
               G = tf([rx 0],[lx rx 1/cx]);
               if app.SignalType.Value == "Step"
                    vr = app.a*unitstep;
               elseif app.SignalType.Value == "Ramp" 
                    vr = app.a*t.*unitstep;
                    %vr = app.a*sawtooth(2*pi*app.f*t);
               elseif app.SignalType.Value =="Sine" 
                    vr = app.a*sin((2*pi*app.f*app.fu*t));
               end
               [y,t] = lsim(G,vr,t);
               plot(app.Input_wave,t,vr,'LineWidth',2);
               plot(app.Output_wave,t,vr,t,y,'LineWidth',2);

           elseif app.vout == "L"
               G = tf([lx 0 0],[lx rx 1/cx]);
               if app.SignalType.Value == "Step"
                    vl = app.a*unitstep;
               elseif app.SignalType.Value == "Ramp" 
                    vl = app.a*t.*unitstep;
                    %vl = app.a*sawtooth((2*pi*app.f*t));
               elseif app.SignalType.Value =="Sine" 
                    vl = app.a*sin((2*pi*app.f*app.fu*t));
               end
               [y,t] = lsim(G,vl,t);
               plot(app.Input_wave,t,vl,'LineWidth',2);
               plot(app.Output_wave,t,vl,t,y,'LineWidth',2);

            elseif app.vout == "C"
                G = tf([1],[lx*cx rx*cx 1]);
                if app.SignalType.Value == "Step"
                    vc = app.a*unitstep;
                elseif app.SignalType.Value == "Ramp" 
                    vc = app.a*t.*unitstep;
                    %vc = app.a*sawtooth((2*pi*app.f*t));
                elseif app.SignalType.Value =="Sine" 
                    vc = app.a*sin((2*pi*app.f*app.fu*t));
                end
                [y,t] = lsim(G,vc,t);
                plot(app.Input_wave,t,vc,'LineWidth',2);
                plot(app.Output_wave,t,vc,t,y,'LineWidth',2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = (-1:0.01:1)';
            unitstep = t>=0;
            plot(app.InputSignalAxes,t,unitstep);
            cal_tranfer(app)
            cal_vout(app)
            finddamp(app)
        end

        % Value changed function: SignalType
        function SignalTypeValueChanged(app, event)
            value = app.SignalType.Value;
            t = (-1:0.01:1)';
            unitstep = t>=0;
            ramp = t.*unitstep;
            sine = sin(2*pi*1*t);
            if value == "Step" 										
	            plot(app.InputSignalAxes,t,unitstep);	
            elseif value == "Ramp" 
                plot(app.InputSignalAxes,t,ramp);	
            elseif value == "Sine" 
                plot(app.InputSignalAxes,t,sine);	
            end

            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: CSlider, LSlider, RSlider
        function RLCSliderValueChanged(app, event)
           app.r = app.RSlider.Value;
           app.l = app.LSlider.Value;
           app.c = app.CSlider.Value;
           app.REditField.Value = app.r;
           app.LEditField.Value = app.l;
           app.CEditField.Value = app.c;

           cal_tranfer(app)
           cal_vout(app)
           finddamp(app)
        end

        % Value changed function: CEditField, LEditField, REditField
        function RLCditFieldValueChanged(app, event)
           app.r = app.REditField.Value;
           app.l = app.LEditField.Value;
           app.c = app.CEditField.Value;
           app.RSlider.Value = app.r;
           app.LSlider.Value = app.l;
           app.CSlider.Value = app.c;

           cal_tranfer(app)
           cal_vout(app)
           finddamp(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "kΩ" 										
	            app.ru = 10^3 ;
            elseif value == "Ω" 
                app.ru = 1 ;
            elseif value == "mΩ"
                app.ru = 10^-3 ;
            elseif value == "μΩ" 
                app.ru = 10^-6 ;
            elseif value == "nΩ" 
                app.ru = 10^-9 ;
            elseif value == "pΩ" 
                app.ru = 10^-12 ;
            end

            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value == "kH" 										
	            app.lu = 10^3 ;
            elseif value == "H" 
                app.lu = 1 ;
            elseif value == "mH"
                app.lu = 10^-3 ;
            elseif value == "μH" 
                app.lu = 10^-6 ;
            elseif value == "nH" 
                app.lu = 10^-9 ;
            elseif value == "pH" 
                app.lu = 10^-12 ;
            end

            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value == "kF" 										
	            app.cu = 10^3 ;
            elseif value == "F" 
                app.cu = 1 ;
            elseif value == "mF"
                app.cu = 10^-3 ;
            elseif value == "μF" 
                app.cu = 10^-6 ;
            elseif value == "nF" 
                app.cu = 10^-9 ;
            elseif value == "pF" 
                app.cu = 10^-12 ;
            end

            RLCditFieldValueChanged(app, event)
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.RButton.Value == 1
                app.vout = "R" ;
            elseif app.LButton.Value == 1
                app.vout = "L" ;
            elseif app.CButton.Value == 1
                app.vout = "C" ;
            end

            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: FrequencySpinner
        function FrequencySpinnerValueChanged(app, event)
            value = app.FrequencySpinner.Value;
            app.f = value ;
            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            value = app.AmplitudeSpinner.Value;
            app.a = value ;
            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            if value == "kHz" 										
	            app.fu = 10^3 ;
            elseif value == "Hz" 
                app.fu = 1 ;
            end

           RLCditFieldValueChanged(app, event)
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.st = app.StartEditField.Value;
            RLCditFieldValueChanged(app, event)
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.lt = app.EndEditField.Value;
            RLCditFieldValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Magnitude_Graph = uiaxes(app.UIFigure);
            title(app.Magnitude_Graph, 'Bode diagram')
            xlabel(app.Magnitude_Graph, 'Frequency (Rad/s)')
            ylabel(app.Magnitude_Graph, 'Magnitude(dB)')
            zlabel(app.Magnitude_Graph, 'Z')
            app.Input_wave = uiaxes(app.UIFigure);
            title(app.Input_wave, 'Input Wavefrom')
            xlabel(app.Input_wave, 'Time (s)')
            ylabel(app.Input_wave, 'Amplitude')
            zlabel(app.Input_wave, 'Z')
            app.Output_wave = uiaxes(app.UIFigure);
            title(app.Output_wave, 'Output Wavefrom')
            xlabel(app.Output_wave, 'Time (s)')
            ylabel(app.Output_wave, 'Amplitude')
            zlabel(app.Output_wave, 'Z')
            app.Phase_Graph = uiaxes(app.UIFigure);
            xlabel(app.Phase_Graph, 'Frequency (Rad/s)')
            ylabel(app.Phase_Graph, 'Phase(deg)')
            zlabel(app.Phase_Graph, 'Z')
            app.CircuitConfigurationPanel = uipanel(app.UIFigure);
            app.CircuitConfigurationPanel.Title = 'Circuit Configuration';
            app.DropDown = uidropdown(app.CircuitConfigurationPanel);
            app.DropDown.Items = {'kΩ', 'Ω', 'mΩ', 'μΩ', 'nΩ', 'pΩ'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Ω';
            app.DropDown_2 = uidropdown(app.CircuitConfigurationPanel);
            app.DropDown_2.Items = {'kH', 'H', 'mH', 'μH', 'nH', 'pH'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'H';
            app.DropDown_3 = uidropdown(app.CircuitConfigurationPanel);
            app.DropDown_3.Items = {'kF', 'F', 'mF', 'μF', 'nF', 'pF'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'F';
            app.TypeofDampLabel = uilabel(app.CircuitConfigurationPanel);
            app.TypeofDampLabel.Text = 'Type of Damp';
            app.TypeOfDamp = uitextarea(app.CircuitConfigurationPanel);
            app.RSlider = uislider(app.CircuitConfigurationPanel);
            app.RSlider.Limits = [-999.999 999.999];
            app.RSlider.MajorTicks = [];
            app.RSlider.MajorTickLabels = {''};
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RLCSliderValueChanged, true);
            app.RSlider.MinorTicks = [];
            app.REditFieldLabel = uilabel(app.CircuitConfigurationPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.CircuitConfigurationPanel, 'numeric');
            app.REditField.Limits = [-999.999 999.999];
            app.REditField.ValueDisplayFormat = '%.3f';
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @RLCditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.CircuitConfigurationPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.CircuitConfigurationPanel, 'numeric');
            app.LEditField.Limits = [-999.999 999.999];
            app.LEditField.ValueDisplayFormat = '%.3f';
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @RLCditFieldValueChanged, true);
            app.LSlider = uislider(app.CircuitConfigurationPanel);
            app.LSlider.Limits = [-999.999 999.999];
            app.LSlider.MajorTicks = [];
            app.LSlider.MajorTickLabels = {''};
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @RLCSliderValueChanged, true);
            app.LSlider.MinorTicks = [];
            app.CSlider = uislider(app.CircuitConfigurationPanel);
            app.CSlider.Limits = [-999.999 999.999];
            app.CSlider.MajorTicks = [];
            app.CSlider.MajorTickLabels = {''};
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @RLCSliderValueChanged, true);
            app.CSlider.MinorTicks = [];
            app.CEditFieldLabel = uilabel(app.CircuitConfigurationPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.CircuitConfigurationPanel, 'numeric');
            app.CEditField.Limits = [-999.999 999.999];
            app.CEditField.ValueDisplayFormat = '%.3f';
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @RLCditFieldValueChanged, true);
            app.Image = uiimage(app.CircuitConfigurationPanel);
            app.Image.ImageSource = '1200px-RLC_series_circuit_v2.png';
            app.SignalConfigurationPanel = uipanel(app.UIFigure);
            app.SignalConfigurationPanel.Title = 'Signal Configuration';
            app.InputSignalAxes = uiaxes(app.SignalConfigurationPanel);
            app.InputSignalAxes.XLim = [0 1];
            app.InputSignalAxes.XTick = [];
            app.InputSignalAxes.XTickLabel = '';
            app.InputSignalAxes.YTick = [];
            app.TypeofSignalLabel = uilabel(app.SignalConfigurationPanel);
            app.TypeofSignalLabel.Text = 'Type of Signal';
            app.SignalType = uidropdown(app.SignalConfigurationPanel);
            app.SignalType.Items = {'Step', 'Ramp', 'Sine'};
            app.SignalType.ValueChangedFcn = createCallbackFcn(app, @SignalTypeValueChanged, true);
            app.SignalType.Value = 'Step';
            app.DropDown_6 = uidropdown(app.SignalConfigurationPanel);
            app.DropDown_6.Items = {'kHz', 'Hz'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = 'Hz';
            app.FrequencySpinnerLabel = uilabel(app.SignalConfigurationPanel);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.SignalConfigurationPanel);
            app.FrequencySpinner.Limits = [1 Inf];
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencySpinnerValueChanged, true);
            app.FrequencySpinner.Value = 50;
            app.AmplitudeSpinnerLabel = uilabel(app.SignalConfigurationPanel);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.SignalConfigurationPanel);
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.AmplitudeSpinner.Value = 1;
            app.OutputWaveformConfigurationPanel = uipanel(app.UIFigure);
            app.OutputWaveformConfigurationPanel.Title = 'Output Waveform Configuration';
            app.ButtonGroup_2 = uibuttongroup(app.OutputWaveformConfigurationPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.RButton = uiradiobutton(app.ButtonGroup_2);
            app.RButton.Text = 'R';
            app.RButton.Value = true;
            app.LButton = uiradiobutton(app.ButtonGroup_2);
            app.LButton.Text = 'L';
            app.CButton = uiradiobutton(app.ButtonGroup_2);
            app.CButton.Text = 'C';
            app.VoutLabel = uilabel(app.OutputWaveformConfigurationPanel);
            app.VoutLabel.Text = 'Vout';
            app.DisplayPanel = uipanel(app.OutputWaveformConfigurationPanel);
            app.DisplayPanel.Title = 'Display';
            app.EndEditFieldLabel = uilabel(app.DisplayPanel);
            app.EndEditFieldLabel.Text = 'End ';
            app.EndEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Value = 0.05;
            app.StartEditFieldLabel = uilabel(app.DisplayPanel);
            app.StartEditFieldLabel.Text = 'Start ';
            app.StartEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end